classdef GUI_Updated < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        uipanel4          matlab.ui.container.Panel
        pushbutton51      matlab.ui.control.Button
        pushbutton52      matlab.ui.control.Button
        pushbutton53      matlab.ui.control.Button
        pushbutton54      matlab.ui.control.Button
        pushbutton55      matlab.ui.control.Button
        pushbutton56      matlab.ui.control.Button
        pushbutton57      matlab.ui.control.Button
        pushbutton58      matlab.ui.control.Button
        pushbutton59      matlab.ui.control.Button
        pushbutton60      matlab.ui.control.Button
        pushbutton61      matlab.ui.control.Button
        Blue1             matlab.ui.control.Image
        uipanel3          matlab.ui.container.Panel
        pushbutton26      matlab.ui.control.Button
        pushbutton27      matlab.ui.control.Button
        pushbutton28      matlab.ui.control.Button
        pushbutton25      matlab.ui.control.Button
        pushbutton29      matlab.ui.control.Button
        pushbutton30      matlab.ui.control.Button
        pushbutton31      matlab.ui.control.Button
        pushbutton32      matlab.ui.control.Button
        pushbutton33      matlab.ui.control.Button
        pushbutton34      matlab.ui.control.Button
        pushbutton35      matlab.ui.control.Button
        Yellow1           matlab.ui.control.Image
        Yellow2           matlab.ui.control.Image
        Yellow3           matlab.ui.control.Image
        Yellow4           matlab.ui.control.Image
        Yellow5           matlab.ui.control.Image
        Yellow6           matlab.ui.control.Image
        uipanel5          matlab.ui.container.Panel
        pushbutton39      matlab.ui.control.Button
        pushbutton40      matlab.ui.control.Button
        pushbutton41      matlab.ui.control.Button
        pushbutton42      matlab.ui.control.Button
        pushbutton43      matlab.ui.control.Button
        pushbutton44      matlab.ui.control.Button
        pushbutton45      matlab.ui.control.Button
        pushbutton46      matlab.ui.control.Button
        pushbutton47      matlab.ui.control.Button
        pushbutton48      matlab.ui.control.Button
        pushbutton49      matlab.ui.control.Button
        uipanel1          matlab.ui.container.Panel
        pushbutton2       matlab.ui.control.Button
        pushbutton3       matlab.ui.control.Button
        pushbutton4       matlab.ui.control.Button
        pushbutton5       matlab.ui.control.Button
        pushbutton6       matlab.ui.control.Button
        pushbutton7       matlab.ui.control.Button
        pushbutton8       matlab.ui.control.Button
        pushbutton9       matlab.ui.control.Button
        pushbutton10      matlab.ui.control.Button
        pushbutton11      matlab.ui.control.Button
        pushbutton23      matlab.ui.control.Button
        Image7            matlab.ui.control.Image
        Image7_2          matlab.ui.control.Image
        Image7_5          matlab.ui.control.Image
        Image7_6          matlab.ui.control.Image
        Image7_7          matlab.ui.control.Image
        Image7_8          matlab.ui.control.Image
        Image7_9          matlab.ui.control.Image
        Image7_10         matlab.ui.control.Image
        Image7_11         matlab.ui.control.Image
        Image7_12         matlab.ui.control.Image
        Image7_13         matlab.ui.control.Image
        Red1              matlab.ui.control.Image
        Red2              matlab.ui.control.Image
        Red3              matlab.ui.control.Image
        Red4              matlab.ui.control.Image
        Red5              matlab.ui.control.Image
        Red6              matlab.ui.control.Image
        PenaltiesLabel    matlab.ui.control.Label
        Label             matlab.ui.control.Label
        Image7_4          matlab.ui.control.Image
        Image7_3          matlab.ui.control.Image
        Image7_14         matlab.ui.control.Image
        Image7_15         matlab.ui.control.Image
        Image7_16         matlab.ui.control.Image
        Image7_17         matlab.ui.control.Image
        Image7_18         matlab.ui.control.Image
        Image7_19         matlab.ui.control.Image
        Image7_20         matlab.ui.control.Image
        Image7_21         matlab.ui.control.Image
        Image7_22         matlab.ui.control.Image
        Image7_23         matlab.ui.control.Image
        Image7_24         matlab.ui.control.Image
        Image7_25         matlab.ui.control.Image
        Image7_26         matlab.ui.control.Image
        Image7_27         matlab.ui.control.Image
        Image7_28         matlab.ui.control.Image
        Image7_29         matlab.ui.control.Image
        Image7_30         matlab.ui.control.Image
        Image7_31         matlab.ui.control.Image
        Image7_32         matlab.ui.control.Image
        Image7_33         matlab.ui.control.Image
        Image7_34         matlab.ui.control.Image
        Image7_35         matlab.ui.control.Image
        Image7_36         matlab.ui.control.Image
        Image7_37         matlab.ui.control.Image
        Image7_38         matlab.ui.control.Image
        Image7_39         matlab.ui.control.Image
        Image7_40         matlab.ui.control.Image
        Image7_41         matlab.ui.control.Image
        Image7_42         matlab.ui.control.Image
        Image7_43         matlab.ui.control.Image
        Image7_44         matlab.ui.control.Image
        Label_2           matlab.ui.control.Label
        TimerLabel        matlab.ui.control.Label
        YourTurnLabel     matlab.ui.control.Label
        YourTurnLamp      matlab.ui.control.Lamp
        Label_9           matlab.ui.control.Label
        GameOverLabel     matlab.ui.control.Label
        PlayerScoreLabel  matlab.ui.control.Label
        Button            matlab.ui.control.Button
        Green1            matlab.ui.control.Image
        Green2            matlab.ui.control.Image
        Green3            matlab.ui.control.Image
        Green4            matlab.ui.control.Image
        Green5            matlab.ui.control.Image
        Green6            matlab.ui.control.Image
        Blue2             matlab.ui.control.Image
        Blue3             matlab.ui.control.Image
        Blue4             matlab.ui.control.Image
        Blue5             matlab.ui.control.Image
        Blue6             matlab.ui.control.Image
        White11           matlab.ui.control.Image
        White21           matlab.ui.control.Image
        White12           matlab.ui.control.Image
        White22           matlab.ui.control.Image
        White13           matlab.ui.control.Image
        White23           matlab.ui.control.Image
        White14           matlab.ui.control.Image
        White24           matlab.ui.control.Image
        White15           matlab.ui.control.Image
        White25           matlab.ui.control.Image
        White16           matlab.ui.control.Image
        White26           matlab.ui.control.Image
    end

    
    properties (Access = private)
        game; % Description
        t;
        value=0;
        turnIncrements=20;
        incrementTime=1;
    end
    
    methods (Access = private)
        
        function app = nextTurn(app)
            app.game=app.game.newTurn();
            temp=app.game.getCurrentValuesS();
            if(temp(5)==0)
                app.t.stop();
                app.game=app.game.endGame();
                app.GameOverLabel.Visible=true;
                app.Label_9.Visible=true;
                app.Label_9.Text=app.game.player.getPlayerScore()+"";
                app.PlayerScoreLabel.Visible=true;
                return;
            end
            app.updatePenalties();
            if(temp(1)==1)
                app.Label_3.Visible=false;
            end
            if(temp(2)==1)
                app.Label_4.Visible=false;
            end
            if(temp(3)==1)
                app.Label_5.Visible=false;
            end
            if(temp(4)==1)
                app.Label_6.Visible=false;
            end
            temp=app.game.getCurrentValuesT();
            if(temp(7)==app.game.player.playerTurn)
                app.YourTurnLamp.Color='green';
            else
                app.YourTurnLamp.Color='red';
            end
            app=app.newNumber(1,temp(1));
            app=app.newNumber(2,temp(2));
            app=app.newNumber(3,temp(3));
            app=app.newNumber(4,temp(4));
            app=app.newNumber(5,temp(5));
            app=app.newNumber(6,temp(6));
        end
        %Below is the code that updates the numbers so you probably
        %want the dice rolling to be here
            
        %Make dice images visible
        function app = makeImagesVisible(app,color,number)
                switch color
                    case 1 %Red
                        switch number
                            case 1
                                app.Red1.Visible=true;
                            case 2 
                                app.Red2.Visible=true;
                            case 3
                                app.Red3.Visible=true;
                            case 4
                                app.Red4.Visible=true;
                            case 5
                                app.Red5.Visible=true;
                            case 6
                                app.Red6.Visible=true;
                        end
                    case 2 %Yellow
                        switch number
                            case 1
                                app.Yellow1.Visible=true;
                            case 2 
                                app.Yellow2.Visible=true;
                            case 3
                                app.Yellow3.Visible=true;
                            case 4
                                app.Yellow4.Visible=true;
                            case 5
                                app.Yellow5.Visible=true;
                            case 6
                                app.Yellow6.Visible=true;
                        end
                    case 3 %Green
                        switch number
                            case 1
                                app.Green1.Visible=true;
                            case 2 
                                app.Green2.Visible=true;
                            case 3
                                app.Green3.Visible=true;
                            case 4
                                app.Green4.Visible=true;
                            case 5
                                app.Green5.Visible=true;
                            case 6
                                app.Green6.Visible=true;
                        end
                    case 4 %Blue
                        switch number
                            case 1
                                app.Blue1.Visible=true;
                            case 2 
                                app.Blue2.Visible=true;
                            case 3
                                app.Blue3.Visible=true;
                            case 4
                                app.Blue4.Visible=true;
                            case 5
                                app.Blue5.Visible=true;
                            case 6
                                app.Blue6.Visible=true;
                        end
                    case 5 %White 1
                        switch number
                            case 1
                                app.White11.Visible=true;
                            case 2 
                                app.White12.Visible=true;
                            case 3
                                app.White13.Visible=true;
                            case 4
                                app.White14.Visible=true;
                            case 5
                                app.White15.Visible=true;
                            case 6
                                app.White16.Visible=true;
                        end
                    case 6 %White 2
                        switch number
                            case 1
                                app.White21.Visible=true;
                            case 2 
                                app.White22.Visible=true;
                            case 3
                                app.White23.Visible=true;
                            case 4
                                app.White24.Visible=true;
                            case 5
                                app.White25.Visible=true;
                            case 6
                                app.White26.Visible=true;
                        end
                end
                
            end
            
        %Make dice images invisible
        function app = makeImagesInvisible(app,color,number)
                switch color
                    case 1 %Red
                        switch number
                            case 1
                                app.Red1.Visible=false;
                            case 2 
                                app.Red2.Visible=false;
                            case 3
                                app.Red3.Visible=false;
                            case 4
                                app.Red4.Visible=false;
                            case 5
                                app.Red5.Visible=false;
                            case 6
                                app.Red6.Visible=false;
                        end
                    case 2 %Yellow
                        switch number
                            case 1
                                app.Yellow1.Visible=false;
                            case 2 
                                app.Yellow2.Visible=false;
                            case 3
                                app.Yellow3.Visible=false;
                            case 4
                                app.Yellow4.Visible=false;
                            case 5
                                app.Yellow5.Visible=false;
                            case 6
                                app.Yellow6.Visible=false;
                        end
                    case 3 %Green
                        switch number
                            case 1
                                app.Green1.Visible=false;
                            case 2 
                                app.Green2.Visible=false;
                            case 3
                                app.Green3.Visible=false;
                            case 4
                                app.Green4.Visible=false;
                            case 5
                                app.Green5.Visible=false;
                            case 6
                                app.Green6.Visible=false;
                        end
                    case 4 %Blue
                        switch number
                            case 1
                                app.Blue1.Visible=false;
                            case 2 
                                app.Blue2.Visible=false;
                            case 3
                                app.Blue3.Visible=false;
                            case 4
                                app.Blue4.Visible=false;
                            case 5
                                app.Blue5.Visible=false;
                            case 6
                                app.Blue6.Visible=false;
                        end
                    case 5 %White 1
                        switch number
                            case 1
                                app.White11.Visible=false;
                            case 2 
                                app.White12.Visible=false;
                            case 3
                                app.White13.Visible=false;
                            case 4
                                app.White14.Visible=false;
                            case 5
                                app.White15.Visible=false;
                            case 6
                                app.White16.Visible=false;
                        end
                    case 6 %White 2
                        switch number
                            case 1
                                app.White21.Visible=false;
                            case 2 
                                app.White22.Visible=false;
                            case 3
                                app.White23.Visible=false;
                            case 4
                                app.White24.Visible=false;
                            case 5
                                app.White25.Visible=false;
                            case 6
                                app.White26.Visible=false;
                        end
                end


        end
            
        function app = newNumber(app,color,number)
            for(i=1:1:6)
                app = app.makeImagesInvisible(color,i);
            end
            temp=randi(6,1,6);
            for(i = 1:1:6)
                app=app.makeImagesVisible(color,temp(i));
                pause(1/3);
                app=app.makeImagesInvisible(color,temp(i));
            end
            app.makeImagesVisible(color,number);
            [y, Fs] = audioread("DICE.WAV");
            sound(y,Fs); 
        end
        
        function app = increment(app)
            app.Label_2.Text=(app.turnIncrements*app.incrementTime-app.incrementTime*rem(app.value,app.turnIncrements)-1)+"";
            app.value=app.value+1;
            if(mod(app.value,app.turnIncrements)==0)
                app=app.nextTurn();
            end
        end
        
        function app = updatePenalties(app)
            app.Label.Text=app.game.player.board.totalPenalties()+"";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_Boardgame_OpeningFcn(app, g)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI_Boardgame (see VARARGIN)
            
            % Choose default command line output for GUI_Boardgame
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            pause(2);
            
            app.game=g;
            
            app.Label_2.Text=app.turnIncrements*app.incrementTime+"";
            
            %Replace with code to start music for the game
            
            app.t = timer;
            app.t.TasksToExecute= 1000000*130;
            app.t.Period=app.incrementTime;
            app.t.TimerFcn = @(~,~) app.increment();
            app.t.ExecutionMode = 'fixedSpacing';
            temp=app.game.getCurrentValuesT();
            app=app.newNumber(1,temp(1));
            app=app.newNumber(2,temp(2));
            app=app.newNumber(3,temp(3));
            app=app.newNumber(4,temp(4));
            app=app.newNumber(5,temp(5));
            app=app.newNumber(6,temp(6));
            if(temp(7)==app.game.player.playerTurn)
                app.YourTurnLamp.Color='green';
            else
                app.YourTurnLamp.Color='red';
            end
            start(app.t);
            
        end

        % Button pushed function: pushbutton2
        function pushbutton2ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",1);
            if(success)
                app.Image7.Visible=true;
            end
        end

        % Button pushed function: pushbutton3
        function pushbutton3ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",2);
            if(success)
                app.Image7_2.Visible=true;
            end
        end

        % Button pushed function: pushbutton4
        function pushbutton4ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",3);
            if(success)
                app.Image7_5.Visible=true;
            end
        end

        % Button pushed function: pushbutton5
        function pushbutton5ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",4);
            if(success)
                app.Image7_6.Visible=true;
            end
        end

        % Button pushed function: pushbutton6
        function pushbutton6ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",5);
            if(success)
                app.Image7_7.Visible=true;
            end
        end

        % Button pushed function: pushbutton7
        function pushbutton7ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",6);
            if(success)
                app.Image7_8.Visible=true;
            end
        end

        % Button pushed function: pushbutton8
        function pushbutton8ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",7);
            if(success)
                app.Image7_9.Visible=true;
            end
        end

        % Button pushed function: pushbutton9
        function pushbutton9ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",8);
            if(success)
                app.Image7_10.Visible=true;
            end
        end

        % Button pushed function: pushbutton10
        function pushbutton10ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",9);
            if(success)
                app.Image7_11.Visible=true;
            end
        end

        % Button pushed function: pushbutton11
        function pushbutton11ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",10);
            if(success)
                app.Image7_12.Visible=true;
            end
        end

        % Button pushed function: pushbutton23
        function pushbutton23ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",11);
            if(success)
                app.Image7_13.Visible=true;
            end
        end

        % Button pushed function: pushbutton25
        function pushbutton25ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",1);
            if(success)
                app.Image7_23.Visible=true;
            end
        end

        % Button pushed function: pushbutton26
        function pushbutton26ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",2);
            if(success)
                app.Image7_24.Visible=true;
            end
        end

        % Button pushed function: pushbutton27
        function pushbutton27ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",3);
            if(success)
                app.Image7_25.Visible=true;
            end
        end

        % Button pushed function: pushbutton28
        function pushbutton28ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",4);
            if(success)
                app.Image7_26.Visible=true;
            end
        end

        % Button pushed function: pushbutton29
        function pushbutton29ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",5);
            if(success)
                app.Image7_27.Visible=true;
            end
        end

        % Button pushed function: pushbutton30
        function pushbutton30ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",6);
            if(success)
                app.Image7_28.Visible=true;
            end
        end

        % Button pushed function: pushbutton31
        function pushbutton31ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",7);
            if(success)
                app.Image7_29.Visible=true;
            end
        end

        % Button pushed function: pushbutton32
        function pushbutton32ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",8);
            if(success)
                app.Image7_30.Visible=true;
            end
        end

        % Button pushed function: pushbutton33
        function pushbutton33ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",9);
            if(success)
                app.Image7_31.Visible=true;
            end
        end

        % Button pushed function: pushbutton34
        function pushbutton34ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",10);
            if(success)
                app.Image7_32.Visible=true;
            end
        end

        % Button pushed function: pushbutton35
        function pushbutton35ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",11);
            if(success)
                app.Image7_33.Visible=true;
            end
        end

        % Button pushed function: pushbutton39
        function pushbutton39ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",1);
            if(success)
                app.Image7_4.Visible=true;
            end
        end

        % Button pushed function: pushbutton40
        function pushbutton40ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",2);
            if(success)
                app.Image7_3.Visible=true;
            end
        end

        % Button pushed function: pushbutton41
        function pushbutton41ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",3);
            if(success)
                app.Image7_14.Visible=true;
            end
        end

        % Button pushed function: pushbutton42
        function pushbutton42ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",4);
            if(success)
                app.Image7_15.Visible=true;
            end
        end

        % Button pushed function: pushbutton43
        function pushbutton43ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",5);
            if(success)
                app.Image7_16.Visible=true;
            end
        end

        % Button pushed function: pushbutton44
        function pushbutton44ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",6);
            if(success)
                app.Image7_17.Visible=true;
            end
        end

        % Button pushed function: pushbutton45
        function pushbutton45ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",7);
            if(success)
                app.Image7_18.Visible=true;
            end
        end

        % Button pushed function: pushbutton46
        function pushbutton46ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",8);
            if(success)
                app.Image7_19.Visible=true;
            end
        end

        % Button pushed function: pushbutton47
        function pushbutton47ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",9);
            if(success)
                app.Image7_20.Visible=true;
            end
        end

        % Button pushed function: pushbutton48
        function pushbutton48ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",10);
            if(success)
                app.Image7_21.Visible=true;
            end
        end

        % Button pushed function: pushbutton49
        function pushbutton49ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",11);
            if(success)
                app.Image7_22.Visible=true;
            end
        end

        % Button pushed function: pushbutton51
        function pushbutton51ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",1);
            if(success)
                app.Image7_34.Visible=true;
            end
        end

        % Button pushed function: pushbutton52
        function pushbutton52ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",2);
            if(success)
                app.Image7_35.Visible=true;
            end
        end

        % Button pushed function: pushbutton53
        function pushbutton53ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",3);
            if(success)
                app.Image7_36.Visible=true;
            end
        end

        % Button pushed function: pushbutton54
        function pushbutton54ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",4);
            if(success)
                app.Image7_37.Visible=true;
            end
        end

        % Button pushed function: pushbutton55
        function pushbutton55ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",5);
            if(success)
                app.Image7_38.Visible=true;
            end
        end

        % Button pushed function: pushbutton56
        function pushbutton56ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",6);
            if(success)
                app.Image7_39.Visible=true;
            end
        end

        % Button pushed function: pushbutton57
        function pushbutton57ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",7);
            if(success)
                app.Image7_40.Visible=true;
            end
        end

        % Button pushed function: pushbutton58
        function pushbutton58ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",8);
            if(success)
                app.Image7_41.Visible=true;
            end
        end

        % Button pushed function: pushbutton59
        function pushbutton59ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",9);
            if(success)
                app.Image7_42.Visible=true;
            end
        end

        % Button pushed function: pushbutton60
        function pushbutton60ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",10);
            if(success)
                app.Image7_43.Visible=true;
            end
        end

        % Button pushed function: pushbutton61
        function pushbutton61ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",11);
            if(success)
                app.Image7_44.Visible=true;
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            try
                app.t.stop();
                delete(app.t)
            catch
            end
            delete(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Rules_Page_1();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI_Boardgame';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Tag = 'uipanel4';
            app.pushbutton51 = uibutton(app.uipanel4, 'push');
            app.pushbutton51.ButtonPushedFcn = createCallbackFcn(app, @pushbutton51ButtonPushed, true);
            app.pushbutton51.Tag = 'pushbutton51';
            app.pushbutton51.Text = '12';
            app.pushbutton52 = uibutton(app.uipanel4, 'push');
            app.pushbutton52.ButtonPushedFcn = createCallbackFcn(app, @pushbutton52ButtonPushed, true);
            app.pushbutton52.Tag = 'pushbutton52';
            app.pushbutton52.Text = '11';
            app.pushbutton53 = uibutton(app.uipanel4, 'push');
            app.pushbutton53.ButtonPushedFcn = createCallbackFcn(app, @pushbutton53ButtonPushed, true);
            app.pushbutton53.Tag = 'pushbutton53';
            app.pushbutton53.Text = '10';
            app.pushbutton54 = uibutton(app.uipanel4, 'push');
            app.pushbutton54.ButtonPushedFcn = createCallbackFcn(app, @pushbutton54ButtonPushed, true);
            app.pushbutton54.Tag = 'pushbutton54';
            app.pushbutton54.Text = '9';
            app.pushbutton55 = uibutton(app.uipanel4, 'push');
            app.pushbutton55.ButtonPushedFcn = createCallbackFcn(app, @pushbutton55ButtonPushed, true);
            app.pushbutton55.Tag = 'pushbutton55';
            app.pushbutton55.Text = '8';
            app.pushbutton56 = uibutton(app.uipanel4, 'push');
            app.pushbutton56.ButtonPushedFcn = createCallbackFcn(app, @pushbutton56ButtonPushed, true);
            app.pushbutton56.Tag = 'pushbutton56';
            app.pushbutton56.Text = '7';
            app.pushbutton57 = uibutton(app.uipanel4, 'push');
            app.pushbutton57.ButtonPushedFcn = createCallbackFcn(app, @pushbutton57ButtonPushed, true);
            app.pushbutton57.Tag = 'pushbutton57';
            app.pushbutton57.Text = '6';
            app.pushbutton58 = uibutton(app.uipanel4, 'push');
            app.pushbutton58.ButtonPushedFcn = createCallbackFcn(app, @pushbutton58ButtonPushed, true);
            app.pushbutton58.Tag = 'pushbutton58';
            app.pushbutton58.Text = '5';
            app.pushbutton59 = uibutton(app.uipanel4, 'push');
            app.pushbutton59.ButtonPushedFcn = createCallbackFcn(app, @pushbutton59ButtonPushed, true);
            app.pushbutton59.Tag = 'pushbutton59';
            app.pushbutton59.Text = '4';
            app.pushbutton60 = uibutton(app.uipanel4, 'push');
            app.pushbutton60.ButtonPushedFcn = createCallbackFcn(app, @pushbutton60ButtonPushed, true);
            app.pushbutton60.Tag = 'pushbutton60';
            app.pushbutton60.Text = '3';
            app.pushbutton61 = uibutton(app.uipanel4, 'push');
            app.pushbutton61.ButtonPushedFcn = createCallbackFcn(app, @pushbutton61ButtonPushed, true);
            app.pushbutton61.Tag = 'pushbutton61';
            app.pushbutton61.Text = '2';
            app.Blue1 = uiimage(app.uipanel4);
            app.Blue1.ImageSource = 'Blue 1.jpg';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Tag = 'uipanel3';
            app.pushbutton26 = uibutton(app.uipanel3, 'push');
            app.pushbutton26.ButtonPushedFcn = createCallbackFcn(app, @pushbutton26ButtonPushed, true);
            app.pushbutton26.Tag = 'pushbutton26';
            app.pushbutton26.Text = '3';
            app.pushbutton27 = uibutton(app.uipanel3, 'push');
            app.pushbutton27.ButtonPushedFcn = createCallbackFcn(app, @pushbutton27ButtonPushed, true);
            app.pushbutton27.Tag = 'pushbutton27';
            app.pushbutton27.Text = '4';
            app.pushbutton28 = uibutton(app.uipanel3, 'push');
            app.pushbutton28.ButtonPushedFcn = createCallbackFcn(app, @pushbutton28ButtonPushed, true);
            app.pushbutton28.Tag = 'pushbutton28';
            app.pushbutton28.Text = '5';
            app.pushbutton25 = uibutton(app.uipanel3, 'push');
            app.pushbutton25.ButtonPushedFcn = createCallbackFcn(app, @pushbutton25ButtonPushed, true);
            app.pushbutton25.Tag = 'pushbutton25';
            app.pushbutton25.Text = '2';
            app.pushbutton29 = uibutton(app.uipanel3, 'push');
            app.pushbutton29.ButtonPushedFcn = createCallbackFcn(app, @pushbutton29ButtonPushed, true);
            app.pushbutton29.Tag = 'pushbutton29';
            app.pushbutton29.Text = '6';
            app.pushbutton30 = uibutton(app.uipanel3, 'push');
            app.pushbutton30.ButtonPushedFcn = createCallbackFcn(app, @pushbutton30ButtonPushed, true);
            app.pushbutton30.Tag = 'pushbutton30';
            app.pushbutton30.Text = '7';
            app.pushbutton31 = uibutton(app.uipanel3, 'push');
            app.pushbutton31.ButtonPushedFcn = createCallbackFcn(app, @pushbutton31ButtonPushed, true);
            app.pushbutton31.Tag = 'pushbutton31';
            app.pushbutton31.Text = '8';
            app.pushbutton32 = uibutton(app.uipanel3, 'push');
            app.pushbutton32.ButtonPushedFcn = createCallbackFcn(app, @pushbutton32ButtonPushed, true);
            app.pushbutton32.Tag = 'pushbutton32';
            app.pushbutton32.Text = '9';
            app.pushbutton33 = uibutton(app.uipanel3, 'push');
            app.pushbutton33.ButtonPushedFcn = createCallbackFcn(app, @pushbutton33ButtonPushed, true);
            app.pushbutton33.Tag = 'pushbutton33';
            app.pushbutton33.Text = '10';
            app.pushbutton34 = uibutton(app.uipanel3, 'push');
            app.pushbutton34.ButtonPushedFcn = createCallbackFcn(app, @pushbutton34ButtonPushed, true);
            app.pushbutton34.Tag = 'pushbutton34';
            app.pushbutton34.Text = '11';
            app.pushbutton35 = uibutton(app.uipanel3, 'push');
            app.pushbutton35.ButtonPushedFcn = createCallbackFcn(app, @pushbutton35ButtonPushed, true);
            app.pushbutton35.Tag = 'pushbutton35';
            app.pushbutton35.Text = '12';
            app.Yellow1 = uiimage(app.uipanel3);
            app.Yellow1.ImageSource = 'Yellow 1.png';
            app.Yellow2 = uiimage(app.uipanel3);
            app.Yellow2.ImageSource = 'Yellow 2.png';
            app.Yellow3 = uiimage(app.uipanel3);
            app.Yellow3.ImageSource = 'Yellow 3.png';
            app.Yellow4 = uiimage(app.uipanel3);
            app.Yellow4.ImageSource = 'Yellow 4.png';
            app.Yellow5 = uiimage(app.uipanel3);
            app.Yellow5.ImageSource = 'Yellow 5.png';
            app.Yellow6 = uiimage(app.uipanel3);
            app.Yellow6.ImageSource = 'Yellow 6.png';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Tag = 'uipanel5';
            app.pushbutton39 = uibutton(app.uipanel5, 'push');
            app.pushbutton39.ButtonPushedFcn = createCallbackFcn(app, @pushbutton39ButtonPushed, true);
            app.pushbutton39.Tag = 'pushbutton39';
            app.pushbutton39.Text = '12';
            app.pushbutton40 = uibutton(app.uipanel5, 'push');
            app.pushbutton40.ButtonPushedFcn = createCallbackFcn(app, @pushbutton40ButtonPushed, true);
            app.pushbutton40.Tag = 'pushbutton40';
            app.pushbutton40.Text = '11';
            app.pushbutton41 = uibutton(app.uipanel5, 'push');
            app.pushbutton41.ButtonPushedFcn = createCallbackFcn(app, @pushbutton41ButtonPushed, true);
            app.pushbutton41.Tag = 'pushbutton41';
            app.pushbutton41.Text = '10';
            app.pushbutton42 = uibutton(app.uipanel5, 'push');
            app.pushbutton42.ButtonPushedFcn = createCallbackFcn(app, @pushbutton42ButtonPushed, true);
            app.pushbutton42.Tag = 'pushbutton42';
            app.pushbutton42.Text = '9';
            app.pushbutton43 = uibutton(app.uipanel5, 'push');
            app.pushbutton43.ButtonPushedFcn = createCallbackFcn(app, @pushbutton43ButtonPushed, true);
            app.pushbutton43.Tag = 'pushbutton43';
            app.pushbutton43.Text = '8';
            app.pushbutton44 = uibutton(app.uipanel5, 'push');
            app.pushbutton44.ButtonPushedFcn = createCallbackFcn(app, @pushbutton44ButtonPushed, true);
            app.pushbutton44.Tag = 'pushbutton44';
            app.pushbutton44.Text = '7';
            app.pushbutton45 = uibutton(app.uipanel5, 'push');
            app.pushbutton45.ButtonPushedFcn = createCallbackFcn(app, @pushbutton45ButtonPushed, true);
            app.pushbutton45.Tag = 'pushbutton45';
            app.pushbutton45.Text = '6';
            app.pushbutton46 = uibutton(app.uipanel5, 'push');
            app.pushbutton46.ButtonPushedFcn = createCallbackFcn(app, @pushbutton46ButtonPushed, true);
            app.pushbutton46.Tag = 'pushbutton46';
            app.pushbutton46.Text = '5';
            app.pushbutton47 = uibutton(app.uipanel5, 'push');
            app.pushbutton47.ButtonPushedFcn = createCallbackFcn(app, @pushbutton47ButtonPushed, true);
            app.pushbutton47.Tag = 'pushbutton47';
            app.pushbutton47.Text = '4';
            app.pushbutton48 = uibutton(app.uipanel5, 'push');
            app.pushbutton48.ButtonPushedFcn = createCallbackFcn(app, @pushbutton48ButtonPushed, true);
            app.pushbutton48.Tag = 'pushbutton48';
            app.pushbutton48.Text = '3';
            app.pushbutton49 = uibutton(app.uipanel5, 'push');
            app.pushbutton49.ButtonPushedFcn = createCallbackFcn(app, @pushbutton49ButtonPushed, true);
            app.pushbutton49.Tag = 'pushbutton49';
            app.pushbutton49.Text = '2';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.pushbutton2 = uibutton(app.uipanel1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2ButtonPushed, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = '2';
            app.pushbutton3 = uibutton(app.uipanel1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3ButtonPushed, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = '3';
            app.pushbutton4 = uibutton(app.uipanel1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4ButtonPushed, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Text = '4';
            app.pushbutton5 = uibutton(app.uipanel1, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton5ButtonPushed, true);
            app.pushbutton5.Tag = 'pushbutton5';
            app.pushbutton5.Text = '5';
            app.pushbutton6 = uibutton(app.uipanel1, 'push');
            app.pushbutton6.ButtonPushedFcn = createCallbackFcn(app, @pushbutton6ButtonPushed, true);
            app.pushbutton6.Tag = 'pushbutton6';
            app.pushbutton6.Text = '6';
            app.pushbutton7 = uibutton(app.uipanel1, 'push');
            app.pushbutton7.ButtonPushedFcn = createCallbackFcn(app, @pushbutton7ButtonPushed, true);
            app.pushbutton7.Tag = 'pushbutton7';
            app.pushbutton7.Text = '7';
            app.pushbutton8 = uibutton(app.uipanel1, 'push');
            app.pushbutton8.ButtonPushedFcn = createCallbackFcn(app, @pushbutton8ButtonPushed, true);
            app.pushbutton8.Tag = 'pushbutton8';
            app.pushbutton8.Text = '8';
            app.pushbutton9 = uibutton(app.uipanel1, 'push');
            app.pushbutton9.ButtonPushedFcn = createCallbackFcn(app, @pushbutton9ButtonPushed, true);
            app.pushbutton9.Tag = 'pushbutton9';
            app.pushbutton9.Text = '9';
            app.pushbutton10 = uibutton(app.uipanel1, 'push');
            app.pushbutton10.ButtonPushedFcn = createCallbackFcn(app, @pushbutton10ButtonPushed, true);
            app.pushbutton10.Tag = 'pushbutton10';
            app.pushbutton10.Text = '10';
            app.pushbutton11 = uibutton(app.uipanel1, 'push');
            app.pushbutton11.ButtonPushedFcn = createCallbackFcn(app, @pushbutton11ButtonPushed, true);
            app.pushbutton11.Tag = 'pushbutton11';
            app.pushbutton11.Text = '11';
            app.pushbutton23 = uibutton(app.uipanel1, 'push');
            app.pushbutton23.ButtonPushedFcn = createCallbackFcn(app, @pushbutton23ButtonPushed, true);
            app.pushbutton23.Tag = 'pushbutton23';
            app.pushbutton23.Text = '12';
            app.Image7 = uiimage(app.uipanel1);
            app.Image7.Visible = 'off';
            app.Image7.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_2 = uiimage(app.uipanel1);
            app.Image7_2.Visible = 'off';
            app.Image7_2.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_5 = uiimage(app.uipanel1);
            app.Image7_5.Visible = 'off';
            app.Image7_5.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_6 = uiimage(app.uipanel1);
            app.Image7_6.Visible = 'off';
            app.Image7_6.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_7 = uiimage(app.uipanel1);
            app.Image7_7.Visible = 'off';
            app.Image7_7.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_8 = uiimage(app.uipanel1);
            app.Image7_8.Visible = 'off';
            app.Image7_8.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_9 = uiimage(app.uipanel1);
            app.Image7_9.Visible = 'off';
            app.Image7_9.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_10 = uiimage(app.uipanel1);
            app.Image7_10.Visible = 'off';
            app.Image7_10.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_11 = uiimage(app.uipanel1);
            app.Image7_11.Visible = 'off';
            app.Image7_11.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_12 = uiimage(app.uipanel1);
            app.Image7_12.Visible = 'off';
            app.Image7_12.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_13 = uiimage(app.uipanel1);
            app.Image7_13.Visible = 'off';
            app.Image7_13.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Red1 = uiimage(app.uipanel1);
            app.Red1.ImageSource = 'Red 1.jpg';
            app.Red2 = uiimage(app.uipanel1);
            app.Red2.ImageSource = 'Red 2.jpg';
            app.Red3 = uiimage(app.uipanel1);
            app.Red3.ImageSource = 'Red 3.jpg';
            app.Red4 = uiimage(app.uipanel1);
            app.Red4.ImageSource = 'Red 4.jpg';
            app.Red5 = uiimage(app.uipanel1);
            app.Red5.ImageSource = 'Red 5.jpg';
            app.Red6 = uiimage(app.uipanel1);
            app.Red6.ImageSource = 'Red 6.jpg';
            app.PenaltiesLabel = uilabel(app.figure1);
            app.PenaltiesLabel.Text = 'Penalties:';
            app.Label = uilabel(app.figure1);
            app.Label.Text = '0';
            app.Image7_4 = uiimage(app.figure1);
            app.Image7_4.Visible = 'off';
            app.Image7_4.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_3 = uiimage(app.figure1);
            app.Image7_3.Visible = 'off';
            app.Image7_3.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_14 = uiimage(app.figure1);
            app.Image7_14.Visible = 'off';
            app.Image7_14.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_15 = uiimage(app.figure1);
            app.Image7_15.Visible = 'off';
            app.Image7_15.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_16 = uiimage(app.figure1);
            app.Image7_16.Visible = 'off';
            app.Image7_16.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_17 = uiimage(app.figure1);
            app.Image7_17.Visible = 'off';
            app.Image7_17.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_18 = uiimage(app.figure1);
            app.Image7_18.Visible = 'off';
            app.Image7_18.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_19 = uiimage(app.figure1);
            app.Image7_19.Visible = 'off';
            app.Image7_19.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_20 = uiimage(app.figure1);
            app.Image7_20.Visible = 'off';
            app.Image7_20.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_21 = uiimage(app.figure1);
            app.Image7_21.Visible = 'off';
            app.Image7_21.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_22 = uiimage(app.figure1);
            app.Image7_22.Visible = 'off';
            app.Image7_22.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_23 = uiimage(app.figure1);
            app.Image7_23.Visible = 'off';
            app.Image7_23.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_24 = uiimage(app.figure1);
            app.Image7_24.Visible = 'off';
            app.Image7_24.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_25 = uiimage(app.figure1);
            app.Image7_25.Visible = 'off';
            app.Image7_25.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_26 = uiimage(app.figure1);
            app.Image7_26.Visible = 'off';
            app.Image7_26.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_27 = uiimage(app.figure1);
            app.Image7_27.Visible = 'off';
            app.Image7_27.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_28 = uiimage(app.figure1);
            app.Image7_28.Visible = 'off';
            app.Image7_28.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_29 = uiimage(app.figure1);
            app.Image7_29.Visible = 'off';
            app.Image7_29.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_30 = uiimage(app.figure1);
            app.Image7_30.Visible = 'off';
            app.Image7_30.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_31 = uiimage(app.figure1);
            app.Image7_31.Visible = 'off';
            app.Image7_31.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_32 = uiimage(app.figure1);
            app.Image7_32.Visible = 'off';
            app.Image7_32.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_33 = uiimage(app.figure1);
            app.Image7_33.Visible = 'off';
            app.Image7_33.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_34 = uiimage(app.figure1);
            app.Image7_34.Visible = 'off';
            app.Image7_34.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_35 = uiimage(app.figure1);
            app.Image7_35.Visible = 'off';
            app.Image7_35.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_36 = uiimage(app.figure1);
            app.Image7_36.Visible = 'off';
            app.Image7_36.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_37 = uiimage(app.figure1);
            app.Image7_37.Visible = 'off';
            app.Image7_37.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_38 = uiimage(app.figure1);
            app.Image7_38.Visible = 'off';
            app.Image7_38.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_39 = uiimage(app.figure1);
            app.Image7_39.Visible = 'off';
            app.Image7_39.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_40 = uiimage(app.figure1);
            app.Image7_40.Visible = 'off';
            app.Image7_40.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_41 = uiimage(app.figure1);
            app.Image7_41.Visible = 'off';
            app.Image7_41.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_42 = uiimage(app.figure1);
            app.Image7_42.Visible = 'off';
            app.Image7_42.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_43 = uiimage(app.figure1);
            app.Image7_43.Visible = 'off';
            app.Image7_43.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_44 = uiimage(app.figure1);
            app.Image7_44.Visible = 'off';
            app.Image7_44.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Label_2 = uilabel(app.figure1);
            app.Label_2.Text = '20';
            app.TimerLabel = uilabel(app.figure1);
            app.TimerLabel.Text = 'Timer:';
            app.YourTurnLabel = uilabel(app.figure1);
            app.YourTurnLabel.Text = 'Your Turn:';
            app.YourTurnLamp = uilamp(app.figure1);
            app.Label_9 = uilabel(app.figure1);
            app.Label_9.Visible = 'off';
            app.Label_9.Text = '000';
            app.GameOverLabel = uilabel(app.figure1);
            app.GameOverLabel.Visible = 'off';
            app.GameOverLabel.Text = 'Game Over';
            app.PlayerScoreLabel = uilabel(app.figure1);
            app.PlayerScoreLabel.Visible = 'off';
            app.PlayerScoreLabel.Text = 'Player Score:';
            app.Button = uibutton(app.figure1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.Green1 = uiimage(app.figure1);
            app.Green1.ImageSource = 'Green 1.jpg';
            app.Green2 = uiimage(app.figure1);
            app.Green2.ImageSource = 'Green 2.jpg';
            app.Green3 = uiimage(app.figure1);
            app.Green3.ImageSource = 'Green 3.jpg';
            app.Green4 = uiimage(app.figure1);
            app.Green4.ImageSource = 'Green 4.jpg';
            app.Green5 = uiimage(app.figure1);
            app.Green5.ImageSource = 'Green 5.jpg';
            app.Green6 = uiimage(app.figure1);
            app.Green6.ImageSource = 'Green 6.jpg';
            app.Blue2 = uiimage(app.figure1);
            app.Blue2.ImageSource = 'Blue 2.jpg';
            app.Blue3 = uiimage(app.figure1);
            app.Blue3.ImageSource = 'Blue 3.jpg';
            app.Blue4 = uiimage(app.figure1);
            app.Blue4.ImageSource = 'Blue 4.jpg';
            app.Blue5 = uiimage(app.figure1);
            app.Blue5.ImageSource = 'Blue 5.jpg';
            app.Blue6 = uiimage(app.figure1);
            app.Blue6.ImageSource = 'Blue 6.jpg';
            app.White11 = uiimage(app.figure1);
            app.White11.ImageSource = 'White 1.jpg';
            app.White21 = uiimage(app.figure1);
            app.White21.ImageSource = 'White 1.jpg';
            app.White12 = uiimage(app.figure1);
            app.White12.ImageSource = 'White 2.jpg';
            app.White22 = uiimage(app.figure1);
            app.White22.ImageSource = 'White 2.jpg';
            app.White13 = uiimage(app.figure1);
            app.White13.ImageSource = 'White 3.jpg';
            app.White23 = uiimage(app.figure1);
            app.White23.ImageSource = 'White 3.jpg';
            app.White14 = uiimage(app.figure1);
            app.White14.ImageSource = 'White 4.jpg';
            app.White24 = uiimage(app.figure1);
            app.White24.ImageSource = 'White 4.jpg';
            app.White15 = uiimage(app.figure1);
            app.White15.ImageSource = 'White 5.jpg';
            app.White25 = uiimage(app.figure1);
            app.White25.ImageSource = 'White 5.jpg';
            app.White16 = uiimage(app.figure1);
            app.White16.ImageSource = 'White 6.jpg';
            app.White26 = uiimage(app.figure1);
            app.White26.ImageSource = 'White 6.jpg';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Updated(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_Boardgame_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end