classdef waiting_waiting_room < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AgameiscurrentlyinprogressLabel  matlab.ui.control.Label
        OncethegameisoveryouwillLabel  matlab.ui.control.Label
        beputintothewaitingroomLabel   matlab.ui.control.Label
        IfyoubelievethistobeinerrorpressthebuttonbelowLabel  matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        obj; % Description
        aPlayer
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [y,Fs]=audioread("Waiting.mp3");
            app.aPlayer=audioplayer(y/5,Fs);
            app.aPlayer.StopFcn = @(src, event) play(src);
            play(app.aPlayer);
        end

        % Callback function
        function BreakoutButtonPushed(app, event)
            app.obj=Breakout();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                stop(app.aPlayer);
                close(app.obj.UIFigure);
            catch
            end
            delete(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            thingSpeakWrite(game.channelIDStatus,[0,0,0,0,0,0,0],'WriteKey',game.writeKeyStatus);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AgameiscurrentlyinprogressLabel = uilabel(app.UIFigure);
            app.AgameiscurrentlyinprogressLabel.Text = 'A game is currently in progress';
            app.OncethegameisoveryouwillLabel = uilabel(app.UIFigure);
            app.OncethegameisoveryouwillLabel.Text = 'Once the game is over, you will';
            app.beputintothewaitingroomLabel = uilabel(app.UIFigure);
            app.beputintothewaitingroomLabel.Text = 'be put into the waiting room.';
            app.IfyoubelievethistobeinerrorpressthebuttonbelowLabel = uilabel(app.UIFigure);
            app.IfyoubelievethistobeinerrorpressthebuttonbelowLabel.Text = 'If you believe this to be in error press the button below';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waiting_waiting_room

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end